classdef right_yanghao_hand_controller < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        Cur_hall_62         matlab.ui.control.Label
        TabGroup            matlab.ui.container.TabGroup
        Motion_control_Tab  matlab.ui.container.Tab
        load_file           matlab.ui.control.Button
        EditFilename        matlab.ui.control.EditField
        Wipe_halldata       matlab.ui.control.Button
        Switchmodsav        matlab.ui.control.CheckBox
        CheckBox            matlab.ui.control.CheckBox
        Sethallarray        matlab.ui.control.DropDown
        Send_goal           matlab.ui.control.Button
        Cur_pre_stohall     matlab.ui.control.TextArea
        Image2              matlab.ui.control.Image
        All_pre_stohall     matlab.ui.control.TextArea
        Stop_Button         matlab.ui.control.Button
        Send_Button         matlab.ui.control.Button
        Keep_goal           matlab.ui.control.Button
        uipanel1            matlab.ui.container.Panel
        serial_state        matlab.ui.control.Lamp
        text0101            matlab.ui.control.Label
        text0102            matlab.ui.control.Label
        COMSwitch           matlab.ui.control.StateButton
        SetBaudRate         matlab.ui.control.DropDown
        SetComNum           matlab.ui.control.DropDown
        PID_control_Tab     matlab.ui.container.Tab
        PIDButton_2         matlab.ui.control.Button
        PIDButton           matlab.ui.control.Button
        PIDPanel            matlab.ui.container.Panel
        daPID_6             matlab.ui.container.Panel
        text0202_37         matlab.ui.control.Label
        daOpen_kd_6         matlab.ui.control.NumericEditField
        text0202_36         matlab.ui.control.Label
        daBend_kd_6         matlab.ui.control.NumericEditField
        text0202_35         matlab.ui.control.Label
        daOpen_ki_6         matlab.ui.control.NumericEditField
        text0202_34         matlab.ui.control.Label
        daBend_ki_6         matlab.ui.control.NumericEditField
        text0202_33         matlab.ui.control.Label
        daOpen_kp_6         matlab.ui.control.NumericEditField
        text0202_32         matlab.ui.control.Label
        daBend_kp_6         matlab.ui.control.NumericEditField
        daPID_5             matlab.ui.container.Panel
        text0202_27         matlab.ui.control.Label
        daOpen_kp_5         matlab.ui.control.NumericEditField
        text0202_26         matlab.ui.control.Label
        daBend_kp_5         matlab.ui.control.NumericEditField
        text0202_31         matlab.ui.control.Label
        daOpen_kd_5         matlab.ui.control.NumericEditField
        text0202_30         matlab.ui.control.Label
        daBend_kd_5         matlab.ui.control.NumericEditField
        text0202_29         matlab.ui.control.Label
        daOpen_ki_5         matlab.ui.control.NumericEditField
        text0202_28         matlab.ui.control.Label
        daBend_ki_5         matlab.ui.control.NumericEditField
        daPID_4             matlab.ui.container.Panel
        text0202_25         matlab.ui.control.Label
        daOpen_kd_4         matlab.ui.control.NumericEditField
        text0202_24         matlab.ui.control.Label
        daBend_kd_4         matlab.ui.control.NumericEditField
        text0202_23         matlab.ui.control.Label
        daOpen_ki_4         matlab.ui.control.NumericEditField
        text0202_22         matlab.ui.control.Label
        daBend_ki_4         matlab.ui.control.NumericEditField
        text0202_21         matlab.ui.control.Label
        daOpen_kp_4         matlab.ui.control.NumericEditField
        text0202_20         matlab.ui.control.Label
        daBend_kp_4         matlab.ui.control.NumericEditField
        daPID_3             matlab.ui.container.Panel
        text0202_19         matlab.ui.control.Label
        daOpen_kd_3         matlab.ui.control.NumericEditField
        text0202_18         matlab.ui.control.Label
        daBend_kd_3         matlab.ui.control.NumericEditField
        text0202_17         matlab.ui.control.Label
        daOpen_ki_3         matlab.ui.control.NumericEditField
        text0202_16         matlab.ui.control.Label
        daBend_ki_3         matlab.ui.control.NumericEditField
        text0202_15         matlab.ui.control.Label
        daOpen_kp_3         matlab.ui.control.NumericEditField
        text0202_14         matlab.ui.control.Label
        daBend_kp_3         matlab.ui.control.NumericEditField
        daPID_2             matlab.ui.container.Panel
        text0202_13         matlab.ui.control.Label
        daOpen_kd_2         matlab.ui.control.NumericEditField
        text0202_12         matlab.ui.control.Label
        daBend_kd_2         matlab.ui.control.NumericEditField
        text0202_11         matlab.ui.control.Label
        daOpen_ki_2         matlab.ui.control.NumericEditField
        text0202_10         matlab.ui.control.Label
        daBend_ki_2         matlab.ui.control.NumericEditField
        text0202_9          matlab.ui.control.Label
        daOpen_kp_2         matlab.ui.control.NumericEditField
        text0202_8          matlab.ui.control.Label
        daBend_kp_2         matlab.ui.control.NumericEditField
        daPID               matlab.ui.container.Panel
        text0202_7          matlab.ui.control.Label
        text0202_6          matlab.ui.control.Label
        text0202_5          matlab.ui.control.Label
        daOpen_kd           matlab.ui.control.NumericEditField
        daOpen_ki           matlab.ui.control.NumericEditField
        daOpen_kp           matlab.ui.control.NumericEditField
        text0202_4          matlab.ui.control.Label
        text0202_3          matlab.ui.control.Label
        text0202_2          matlab.ui.control.Label
        daBend_kd           matlab.ui.control.NumericEditField
        daBend_ki           matlab.ui.control.NumericEditField
        daBend_kp           matlab.ui.control.NumericEditField
        Tab_2               matlab.ui.container.Tab
        UITable             matlab.ui.control.Table
        Cur_hall_63         matlab.ui.control.Label
        Tar_hall_63         matlab.ui.control.Spinner
        Tar_hall_62         matlab.ui.control.Spinner
        Cur_hall_54         matlab.ui.control.Label
        Tar_hall_54         matlab.ui.control.Spinner
        Cur_hall_61         matlab.ui.control.Label
        Tar_hall_61         matlab.ui.control.Spinner
        Cur_hall_53         matlab.ui.control.Label
        Tar_hall_53         matlab.ui.control.Spinner
        Cur_hall_52         matlab.ui.control.Label
        Tar_hall_52         matlab.ui.control.Spinner
        Cur_hall_51         matlab.ui.control.Label
        Tar_hall_51         matlab.ui.control.Spinner
        Cur_hall_44         matlab.ui.control.Label
        Tar_hall_43         matlab.ui.control.Spinner
        Cur_hall_34         matlab.ui.control.Label
        Tar_hall_33         matlab.ui.control.Spinner
        Cur_hall_24         matlab.ui.control.Label
        Tar_hall_23         matlab.ui.control.Spinner
        Cur_hall_14         matlab.ui.control.Label
        Tar_hall_13         matlab.ui.control.Spinner
        Cur_hall_43         matlab.ui.control.Label
        Tar_hall_42         matlab.ui.control.Spinner
        Cur_hall_33         matlab.ui.control.Label
        Tar_hall_32         matlab.ui.control.Spinner
        Cur_hall_42         matlab.ui.control.Label
        Tar_hall_41         matlab.ui.control.Spinner
        Cur_hall_32         matlab.ui.control.Label
        Tar_hall_31         matlab.ui.control.Spinner
        Cur_hall_22         matlab.ui.control.Label
        Tar_hall_21         matlab.ui.control.Spinner
        Tar_hall_22         matlab.ui.control.Spinner
        Cur_hall_23         matlab.ui.control.Label
        Tar_hall_12         matlab.ui.control.Spinner
        Cur_hall_13         matlab.ui.control.Label
        Tar_hall_11         matlab.ui.control.Spinner
        Cur_hall_12         matlab.ui.control.Label
        Cur_hall_41         matlab.ui.control.Label
        Cur_hall_31         matlab.ui.control.Label
        Cur_hall_21         matlab.ui.control.Label
        Cur_hall_11         matlab.ui.control.Label
        Image               matlab.ui.control.Image
    end


    properties (Access = private)
        SerialObject;%串口对象
        Motion_PID_Switch = 1;
        single_conti_flag = 0;%
        Cur_hall_data = zeros(1,26);%存储当前霍尔值
        Tar_hall_redata = zeros(1,21);%存储返回的目标霍尔值
        Tar_hall_data = zeros(1,19);%存储准备发送的目标霍尔值
        Tar2stored_hall_data = zeros(1,19);%发送转存预存的临时数组
        Stored_tar_hall_data = [];%预存连续动作的目标霍尔值
        temp_text = [];
    end

    methods (Access = private)
        function readSerialData(app,src,~)%后面两个参数随便写，但是不能没有
            % 确定接收缓存中的数据量
            n = src.NumBytesAvailable;%读取缓存数据的长度

            if app.Motion_PID_Switch==1
                if n==50 %如果长度等于26，则说明传回是当前霍尔值
                    User_data = read(app.SerialObject,50,"uint8");%接收串口数据同时清空串口的缓存
                    for i = 1:24
                        app.Cur_hall_data(i) = bitor(bitshift(User_data(2*i-1),8),User_data(2*i))-32768;
                    end
                    %改变显示顺序，下位机采集霍尔值排列顺序和上位机显示顺序不同
                    app.Cur_hall_data = [app.Cur_hall_data(13:16),app.Cur_hall_data(9:12),app.Cur_hall_data(5:8),app.Cur_hall_data(1:4),app.Cur_hall_data(17:24)];
                    Disp_Cur_hall(app);%将读取的当前霍尔值显示在gui面板上
                elseif n==40 %长度为20，则说明传回是目标霍尔值
                    User_data = read(app.SerialObject,40,"uint8");%接收目标霍尔值
                    for i = 1:19
                        app.Tar_hall_redata(i) = bitor(bitshift(User_data(2*i-1),8),User_data(2*i))-32768;
                    end
                    app.Tar_hall_redata = [app.Tar_hall_redata(10:12),app.Tar_hall_redata(7:9),app.Tar_hall_redata(4:6),app.Tar_hall_redata(1:3),app.Tar_hall_redata(13:19)];
                    app.Tar2stored_hall_data = app.Tar_hall_data;%预备转存目标霍尔值
%                     app.Tar_hall_data =  zeros(1,19);%将准备发送的目标霍尔值清零
                    Disp_Tar_hall(app);%显示目标霍尔值
                    %处于发送保存数据状态下，切换文本框内当前显示的数据
                    if app.single_conti_flag && app.CheckBox.Value
                        [m,n] = size(app.Stored_tar_hall_data);%求的矩阵的行列数
                        i = str2double(app.Sethallarray.Value);%读取当前显示的预存目标霍尔值的行数
                        if i< m
                            app.Sethallarray.Value = num2str(i+1);%显示下一行预存目标霍尔值
                            app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(i+1,:));%显示当前预存目标霍尔值
                        end
                        app.single_conti_flag = 0;
                    end
                elseif n==3
                    User_data = readline(app.SerialObject);
                    if User_data(1) == 's'
                        app.Tar_hall_data = zeros(1,19);
                        app.Tar_hall_redata = zeros(1,21);
                        Disp_Tar_hall(app);%显示目标霍尔值
                    end
                end
            elseif app.Motion_PID_Switch==2%传回PID参数


            end
            flush(app.SerialObject);%清空串口的输入输出的数据缓存，否则导致数据累积。
        end

        function Disp_Cur_hall(app)%将读取的当前霍尔值显示在gui面板上
            app.Cur_hall_11.Text = num2str(app.Cur_hall_data(1));
            app.Cur_hall_12.Text = num2str(app.Cur_hall_data(2));
            app.Cur_hall_13.Text = num2str(app.Cur_hall_data(3));
            app.Cur_hall_14.Text = num2str(app.Cur_hall_data(4));

            app.Cur_hall_21.Text = num2str(app.Cur_hall_data(5));
            app.Cur_hall_22.Text = num2str(app.Cur_hall_data(6));
            app.Cur_hall_23.Text = num2str(app.Cur_hall_data(7));
            app.Cur_hall_24.Text = num2str(app.Cur_hall_data(8));

            app.Cur_hall_31.Text = num2str(app.Cur_hall_data(9));
            app.Cur_hall_32.Text = num2str(app.Cur_hall_data(10));
            app.Cur_hall_33.Text = num2str(app.Cur_hall_data(11));
            app.Cur_hall_34.Text = num2str(app.Cur_hall_data(12));

            app.Cur_hall_41.Text = num2str(app.Cur_hall_data(13));
            app.Cur_hall_42.Text = num2str(app.Cur_hall_data(14));
            app.Cur_hall_43.Text = num2str(app.Cur_hall_data(15));
            app.Cur_hall_44.Text = num2str(app.Cur_hall_data(16));

            app.Cur_hall_51.Text = num2str(app.Cur_hall_data(17));
            app.Cur_hall_52.Text = num2str(app.Cur_hall_data(18));
            app.Cur_hall_53.Text = num2str(app.Cur_hall_data(19));
            app.Cur_hall_54.Text = num2str(app.Cur_hall_data(20));

            app.Cur_hall_61.Text = num2str(app.Cur_hall_data(21));
            app.Cur_hall_62.Text = num2str(app.Cur_hall_data(22));
            app.Cur_hall_63.Text = num2str(app.Cur_hall_data(23));
        end

        function Disp_Tar_hall(app)
            %将接收的目标霍尔值显示在面板上
            app.Tar_hall_11.Value = app.Tar_hall_redata(1);
            app.Tar_hall_12.Value = app.Tar_hall_redata(2);
            app.Tar_hall_13.Value = app.Tar_hall_redata(3);

            app.Tar_hall_21.Value = app.Tar_hall_redata(4);
            app.Tar_hall_22.Value = app.Tar_hall_redata(5);
            app.Tar_hall_23.Value = app.Tar_hall_redata(6);

            app.Tar_hall_31.Value = app.Tar_hall_redata(7);
            app.Tar_hall_32.Value = app.Tar_hall_redata(8);
            app.Tar_hall_33.Value = app.Tar_hall_redata(9);

            app.Tar_hall_41.Value = app.Tar_hall_redata(10);
            app.Tar_hall_42.Value = app.Tar_hall_redata(11);
            app.Tar_hall_43.Value = app.Tar_hall_redata(12);

            app.Tar_hall_51.Value = app.Tar_hall_redata(13);
            app.Tar_hall_52.Value = app.Tar_hall_redata(14);
            app.Tar_hall_53.Value = app.Tar_hall_redata(15);
            app.Tar_hall_54.Value = app.Tar_hall_redata(16);

            app.Tar_hall_61.Value = app.Tar_hall_redata(17);
            app.Tar_hall_62.Value = app.Tar_hall_redata(18);
            app.Tar_hall_63.Value = app.Tar_hall_redata(19);

            %将显示目标霍尔值的微调器的背景颜色变灰，表示回传成功。
            %变成白色表明修改目标霍尔值，可以发送给下位机。
            app.Tar_hall_11.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_12.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_13.BackgroundColor = [0.8,0.8,0.8];

            app.Tar_hall_21.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_22.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_23.BackgroundColor = [0.8,0.8,0.8];

            app.Tar_hall_31.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_32.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_33.BackgroundColor = [0.8,0.8,0.8];

            app.Tar_hall_41.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_42.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_43.BackgroundColor = [0.8,0.8,0.8];

            app.Tar_hall_51.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_52.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_53.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_54.BackgroundColor = [0.8,0.8,0.8];

            app.Tar_hall_61.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_62.BackgroundColor = [0.8,0.8,0.8];
            app.Tar_hall_63.BackgroundColor = [0.8,0.8,0.8];

        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %串口创建
            com_list = serialportlist("all");
            try
                app.SetComNum.Items = com_list;
                app.SetBaudRate.Value = "115200";
            catch
                app.portData.PortName = 'null';
                errordlg('没有搜索到任何可用端口','端口错误');
            end
        end

        % Callback function
        function SliderValueChanging(app, event)
            changingValue = event.Value;
            app.Cur_hall_11.Text = num2str(round(changingValue));
        end

        % Callback function
        function SliderValueChanged(app, event)
            %             value = app.Slider.Value;
            %             app.Cur_hall_11.Text = num2str(round(value));
        end

        % Callback function
        function Slider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.EditFilename.Value = round(changingValue);
        end

        % Drop down opening function: SetComNum
        function SetComNumDropDownOpening(app, event)
            com_list = serialportlist("all");
            try
                app.SetComNum.Items = com_list;
                app.SetBaudRate.Value = "115200";
            catch
                app.portData.PortName = 'null';
                errordlg('没有搜索到任何可用端口','端口错误');
            end
        end

        % Value changed function: COMSwitch
        function COMSwitchValueChanged(app, event)
            value = app.COMSwitch.Value;
            if value
                try
                    app.SerialObject = serialport(app.SetComNum.Value,str2double(app.SetBaudRate.Value));
                    configureTerminator(app.SerialObject,"CR/LF");%接收到ASCII码回车符时触发终止,"LF"
                    configureCallback(app.SerialObject,"terminator",@app.readSerialData);%设置回调函数readSerialData 接收串口返回的数据
                    app.serial_state.Color =[0,1,0];%将指示灯打开，显示为绿色。指示灯关闭，显示为灰色
                    app.Send_Button.Enable = 1;%使能发送按钮
                    app.Stop_Button.Enable = 1;%使能停止按钮
                    app.Keep_goal.Enable = 1;%使能保存数据按钮
                    app.load_file.Enable = 1;%使能载入文件按钮
                    app.Wipe_halldata.Enable = 1;%使能清空数据按钮
                    
                catch
                    errordlg('该串口可能被其它应用占用或串口命名错误！');
                    app.COMSwitch.Value = 0;
                    app.serial_state.Color = [0.65,0.65,0.65];%指示灯变为灰色，表示不可用
                    return
                end
            else
                delete(app.SerialObject);%删除已经打开的实例对象，即可关闭串口
                app.serial_state.Color = [0.65,0.65,0.65];%指示灯变为灰色，表示不可用
                app.Send_Button.Enable = 0;%使能发送按钮停止
                app.Stop_Button.Enable = 0;%使能停止按钮停止
                app.Keep_goal.Enable = 0;%使能保存数据按钮
                app.load_file.Enable = 0;%使能载入文件按钮
                app.Wipe_halldata.Enable = 0;%使能清空数据按钮
                app.Send_goal.Enable = 0;%使能发送数据按钮
            end

        end

        % Button down function: Motion_control_Tab
        function Motion_control_TabButtonDown(app, event)
            app.Motion_PID_Switch = 1;%赋值为1，表明处于运动控制状态
        end

        % Button down function: PID_control_Tab
        function PID_control_TabButtonDown(app, event)
            app.Motion_PID_Switch = 2;%赋值为2，表明处于pid调制状态
        end

        % Button pushed function: Send_Button
        function Send_ButtonPushed(app, event)
            mark = 0;%判断目标霍尔值不为0才发送给串口
            Send_data = [];
            %app.Tar_hall_data = 200:218;%测试代码
            for i=1:19
                temp_a = uint16(app.Tar_hall_data(i) + 32768);
                high8 = bitshift(temp_a,-8);
                low8 = bitand(temp_a,255);
                Send_data = [Send_data,uint8(high8),uint8(low8)];
                if app.Tar_hall_data(i)>0
                    mark = 1;
                end
            end
            %             app.Tar_hall_data = zeros(1,19);
            if mark
                Send_data = [Send_data(19:24),Send_data(13:18),Send_data(7:12),Send_data(1:6),Send_data(25:38),13,10,1];%重新调整手指标志位
                write(app.SerialObject,Send_data,"uint8");%发送目标霍尔值至下位机
                mark = 0;%重新置零
            end
        end

        % Value changed function: Tar_hall_11
        function Tar_hall_11ValueChanged(app, event)
            value = app.Tar_hall_11.Value;

            app.Tar_hall_11.BackgroundColor = [1,1,1];%将背景色变为白色，表示可以输入目标霍尔值
            if value>=0
                app.Tar_hall_data(1) = value;%把食指dip目标值赋值给目标霍尔值数组
                app.Tar2stored_hall_data(1) = value;%把目标霍尔值存入预备动作霍尔值数组
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_11.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_12
        function Tar_hall_12ValueChanged(app, event)
            value = app.Tar_hall_12.Value;

            app.Tar_hall_12.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(2) = value;
                app.Tar2stored_hall_data(2) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_12.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_13
        function Tar_hall_13ValueChanged(app, event)
            value = app.Tar_hall_13.Value;

            app.Tar_hall_13.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(3) = value;
                app.Tar2stored_hall_data(3) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_13.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_21
        function Tar_hall_21ValueChanged(app, event)
            value = app.Tar_hall_21.Value;

            app.Tar_hall_21.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(4) = value;
                app.Tar2stored_hall_data(4) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_21.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_22
        function Tar_hall_22ValueChanged(app, event)
            value = app.Tar_hall_22.Value;

            app.Tar_hall_22.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(5) = value;
                app.Tar2stored_hall_data(5) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_22.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_23
        function Tar_hall_23ValueChanged(app, event)
            value = app.Tar_hall_23.Value;

            app.Tar_hall_23.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(6) = value;
                app.Tar2stored_hall_data(6) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_23.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_31
        function Tar_hall_31ValueChanged(app, event)
            value = app.Tar_hall_31.Value;

            app.Tar_hall_31.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(7) = value;
                app.Tar2stored_hall_data(7) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_31.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_32
        function Tar_hall_32ValueChanged(app, event)
            value = app.Tar_hall_32.Value;

            app.Tar_hall_32.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(8) = value;
                app.Tar2stored_hall_data(8) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_32.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_33
        function Tar_hall_33ValueChanged(app, event)
            value = app.Tar_hall_33.Value;

            app.Tar_hall_33.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(9) = value;
                app.Tar2stored_hall_data(9) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_33.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_41
        function Tar_hall_41ValueChanged(app, event)
            value = app.Tar_hall_41.Value;

            app.Tar_hall_41.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(10) = value;%预存目标霍尔值
                app.Tar2stored_hall_data(10) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_41.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_42
        function Tar_hall_42ValueChanged(app, event)
            value = app.Tar_hall_42.Value;

            app.Tar_hall_42.BackgroundColor = [1,1,1];
            if value >= 0
                app.Tar_hall_data(11) = value;
                app.Tar2stored_hall_data(11) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_42.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_43
        function Tar_hall_43ValueChanged(app, event)
            value = app.Tar_hall_43.Value;

            app.Tar_hall_43.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(12) = value;
                app.Tar2stored_hall_data(12) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_43.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_51
        function Tar_hall_51ValueChanged(app, event)
            value = app.Tar_hall_51.Value;

            app.Tar_hall_51.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(13) = value;
                app.Tar2stored_hall_data(13) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_51.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_52
        function Tar_hall_52ValueChanged(app, event)
            value = app.Tar_hall_52.Value;

            app.Tar_hall_52.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(14) = value;
                app.Tar2stored_hall_data(14) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_52.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_53
        function Tar_hall_53ValueChanged(app, event)
            value = app.Tar_hall_53.Value;

            app.Tar_hall_53.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(15) = value;
                app.Tar2stored_hall_data(15) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_53.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_54
        function Tar_hall_54ValueChanged(app, event)
            value = app.Tar_hall_54.Value;

            app.Tar_hall_54.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(16) = value;
                app.Tar2stored_hall_data(16) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_54.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_61
        function Tar_hall_61ValueChanged(app, event)
            value = app.Tar_hall_61.Value;

            app.Tar_hall_61.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(17) = value;
                app.Tar2stored_hall_data(17) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_61.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_62
        function Tar_hall_62ValueChanged(app, event)
            value = app.Tar_hall_62.Value;

            app.Tar_hall_62.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(18) = value;
                app.Tar2stored_hall_data(18) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_62.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Value changed function: Tar_hall_63
        function Tar_hall_63ValueChanged(app, event)
            value = app.Tar_hall_63.Value;

            app.Tar_hall_63.BackgroundColor = [1,1,1];
            if value>=0
                app.Tar_hall_data(19) = value;
                app.Tar2stored_hall_data(19) = value;
            else
                %如果目标值为0，则背景色为灰色，目标霍尔值保持为0
                app.Tar_hall_63.BackgroundColor = [0.8,0.8,0.8];
            end
        end

        % Button pushed function: Stop_Button
        function Stop_ButtonPushed(app, event)
            %发送一个字符s，表示停止仿生手的运动，目标霍尔值全部置零。
            Send_data = [115,13,10];%发送停止信号
            write(app.SerialObject,Send_data,"uint8");

        end

        % Drop down opening function: SetBaudRate
        function SetBaudRateDropDownOpening(app, event)

        end

        % Button pushed function: Keep_goal
        function Keep_goalPushed(app, event)
            mark = 0;%判断目标霍尔值不为0才发送给串口
            temp_text = [];
            for i=1:19
                if app.Tar2stored_hall_data(i)>0
                    mark = 1;
                end
            end

            if mark

                if app.Switchmodsav.Value
                    value = app.Sethallarray.Value;%读取预存动作需要修改的
                    modify_i = str2double(value);%
                    app.Stored_tar_hall_data(modify_i,:) = app.Tar2stored_hall_data;%插入需要修改的行（测试代码）
                    app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(modify_i,:));%显示预存目标霍尔值
                else
                    app.Stored_tar_hall_data = [app.Stored_tar_hall_data;app.Tar2stored_hall_data];
                end
                [m,n] = size(app.Stored_tar_hall_data);%求的矩阵的行列数

                app.Sethallarray.Items = string(1:m);%显示行数，供选择
                app.Sethallarray.Value = string(m);%显示最后一行的行数
                for j = 1:m
                    temp_text = [temp_text,num2str(app.Stored_tar_hall_data(j,:)),""];
                end
                app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(m,:));%显示当前预存目标霍尔值
                app.All_pre_stohall.Value = temp_text;%显示全部预存目标霍尔值
                %存入mat文件中
                filename = app.EditFilename.Value;%读取输入的文件名
                filename = strcat(filename,".mat");%加入后缀名
                hall_data = app.Stored_tar_hall_data;%将需要存储的目标霍尔值放入保存文件的变量中
                save(filename,"hall_data");
                
                app.Tar2stored_hall_data = zeros(1,19);%发送转存预存的临时数组清零
                if isempty(app.Stored_tar_hall_data) ~= 1
                    app.Send_goal.Enable = 1;%允许发送数据按钮使能
                end
            end
        end

        % Drop down opening function: Sethallarray
        function SethallarrayDropDownOpening(app, event)

        end

        % Value changed function: Sethallarray
        function SethallarrayValueChanged(app, event)
            value = app.Sethallarray.Value;
            m = str2double(value);
            app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(m,:));%显示当前预存目标霍尔值
        end

        % Value changed function: Switchmodsav
        function SwitchmodsavValueChanged(app, event)
            value = app.Switchmodsav.Value;
            if value
                app.Keep_goal.Text = "修改数据";
                app.All_pre_stohall.Editable = 1;%开启预存霍尔值显示文本框的修改权限
                app.Cur_pre_stohall.Editable = 1;%开启当前预存霍尔值显示文本框的修改权限
                app.Wipe_halldata.Enable = 0;%开启修改模式，关闭清空数据按钮
            else
                app.Keep_goal.Text = "保存数据";
                app.All_pre_stohall.Editable = 0;%关闭预存霍尔值显示文本框的修改权限
                app.Cur_pre_stohall.Editable = 0;%当前预存霍尔值显示文本框的修改权限
                app.Wipe_halldata.Enable = 1;%关闭修改模式，开启清空数据按钮
            end
            
        end

        % Button pushed function: load_file
        function load_fileButtonPushed(app, event)
            temp_text = [];
            value = app.EditFilename.Value;%读取输入的文件名
            value = strcat(value,".mat");%加入后缀名
            load(value);%加载文件中的变量
            app.Stored_tar_hall_data = hall_data;
            [m,n] = size(app.Stored_tar_hall_data);%求的矩阵的行列数

            app.Sethallarray.Items = string(1:m);%显示行数，供选择
            app.Sethallarray.Value = string(m);%显示最后一行的行数
            for j = 1:m
                temp_text = [temp_text,num2str(app.Stored_tar_hall_data(j,:)),""];
            end
            app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(m,:));%显示当前预存目标霍尔值最后一个动作
            app.All_pre_stohall.Value = temp_text;%显示全部预存目标霍尔值
            if isempty(app.Stored_tar_hall_data) ~= 1
                app.Send_goal.Enable = 1;%允许发送数据按钮使能
            end
        end

        % Button pushed function: Wipe_halldata
        function Wipe_halldataButtonPushed(app, event)
            temp_text = [];
            if app.Switchmodsav.Value
                i = str2double(app.Sethallarray.Value);%读取当前显示的预存目标霍尔值的行数
                app.Stored_tar_hall_data(i,:) = [];%清空指定行的预存目标霍尔值
                m = size(app.Stored_tar_hall_data);%求的矩阵的行列数

                app.Sethallarray.Items = string(1:m);%显示行数，供选择
                app.Sethallarray.Value = string(m);%显示最后一行的行数
                for j = 1:m(1)
                    temp_text = [temp_text,num2str(app.Stored_tar_hall_data(j,:)),""];
                end
                app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(m,:));%显示当前预存目标霍尔值
                app.All_pre_stohall.Value = temp_text;%显示全部预存目标霍尔值
            else
                app.Cur_pre_stohall.Value = '';%清空显示当前预存目标霍尔值
                app.All_pre_stohall.Value = '';%清空显示全部预存目标霍尔值
                app.Sethallarray.Items = "0";%清空显示行数
                app.Sethallarray.Value = "0";%清空当前显示行数
                app.Stored_tar_hall_data = [];%清空预存目标霍尔值
                app.Send_goal.Enable = 0;%允许发送数据按钮使能
            end
            

        end

        % Button pushed function: Send_goal
        function Send_goalButtonPushed(app, event)
            mark = 0;%判断目标霍尔值不为0才发送给串口
            Send_data = [];
            %app.Tar_hall_data = 200:218;%测试代码
            %[m,n] = size(app.Stored_tar_hall_data);%求的矩阵的行列数
            m = str2double(app.Sethallarray.Value);%获取需要发送的预存霍尔值的行数

            for i=1:19
                temp_a = uint16(app.Stored_tar_hall_data(m,i) + 32768);
                high8 = bitshift(temp_a,-8);
                low8 = bitand(temp_a,255);
                Send_data = [Send_data,uint8(high8),uint8(low8)];
                if app.Stored_tar_hall_data(m,i)>0
                    mark = 1;
                end
            end
            %             app.Tar_hall_data = zeros(1,19);
            if mark
                Send_data = [Send_data(19:24),Send_data(13:18),Send_data(7:12),Send_data(1:6),Send_data(25:38),13,10,1];%重新调整手指标志位
                write(app.SerialObject,Send_data,"uint8");%发送目标霍尔值至下位机
                mark = 0;%重新置零
                app.single_conti_flag = 1;%开启串口接收返回数据时切换当前预存霍尔值显示
            end
        end

        % Value changed function: All_pre_stohall
        function All_pre_stohallValueChanged(app, event)
            value = app.All_pre_stohall.Value;
            temp_text = [];
            if isempty(value) ~= 1
                try
                    n = size(value);
                    app.Stored_tar_hall_data = [];%清空数组，准备装载新预存目标霍尔值
                    %将文本框的元胞数组转换为预存目标霍尔值,不要使用str2double，容易出错。
                    for i = 1:(n(1)/2)
                        app.Stored_tar_hall_data(i,:) = str2num(string(value(2*i-1)));
                    end
                    app.Stored_tar_hall_data
                    i = size(app.Stored_tar_hall_data);%求的矩阵的行列数
                    app.Sethallarray.Items = string(1:i(1));%显示行数，供选择
                    for j = 1:i(1)
                        temp_text = [temp_text,num2str(app.Stored_tar_hall_data(j,:)),""];
                    end
                    app.All_pre_stohall.Value = temp_text;%显示全部预存目标霍尔值
                    app.Sethallarray.Items = string(1:i(1));%显示行数，供选择
                    app.Sethallarray.Value = string(i(1));%显示最后一行的行数
                    app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(i(1),:));%显示当前预存目标霍尔值
                    %存入mat文件中
                    filename = app.EditFilename.Value;%读取输入的文件名
                    filename = strcat(filename,".mat");%加入后缀名
                    hall_data = app.Stored_tar_hall_data;%将需要存储的目标霍尔值放入保存文件的变量中
                    save(filename,"hall_data");
                catch
                    errordlg('请检查输入行列式数目或格式是否错误！');
                end
                
            end
        end

        % Value changed function: Cur_pre_stohall
        function Cur_pre_stohallValueChanged(app, event)
            value = app.Cur_pre_stohall.Value;
            temp_text = [];
            if isempty(value) ~= 1
                try
                    %获取当前显示的预存目标霍尔值的行数
                    n = app.Sethallarray.Value;
                    m = str2double(n);
                    %将文本框当前的元胞数组转换为预存目标霍尔值,不要使用str2double，容易出错。
                    app.Stored_tar_hall_data(m,:) = str2num(string(value));
                    i = size(app.Stored_tar_hall_data);%求的矩阵的行列数

                    for j = 1:i(1)
                        temp_text = [temp_text,num2str(app.Stored_tar_hall_data(j,:)),""];
                    end
                    app.All_pre_stohall.Value = temp_text;%显示全部预存目标霍尔值
                    app.Cur_pre_stohall.Value = num2str(app.Stored_tar_hall_data(m,:));%显示当前预存目标霍尔值
                    %存入mat文件中
                    filename = app.EditFilename.Value;%读取输入的文件名
                    filename = strcat(filename,".mat");%加入后缀名
                    hall_data = app.Stored_tar_hall_data;%将需要存储的目标霍尔值放入保存文件的变量中
                    save(filename,"hall_data");
                catch
                    errordlg('请检查输入行列式数目或格式是否错误！');
                end
                
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'youshou.jpg');
            app.Cur_hall_11 = uilabel(app.UIFigure);
            app.Cur_hall_11.Text = '0';
            app.Cur_hall_21 = uilabel(app.UIFigure);
            app.Cur_hall_21.Text = '0';
            app.Cur_hall_31 = uilabel(app.UIFigure);
            app.Cur_hall_31.Text = '0';
            app.Cur_hall_41 = uilabel(app.UIFigure);
            app.Cur_hall_41.Text = '0';
            app.Cur_hall_12 = uilabel(app.UIFigure);
            app.Cur_hall_12.Text = '0';
            app.Tar_hall_11 = uispinner(app.UIFigure);
            app.Tar_hall_11.Limits = [0 Inf];
            app.Tar_hall_11.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_11ValueChanged, true);
            app.Cur_hall_13 = uilabel(app.UIFigure);
            app.Cur_hall_13.Text = '0';
            app.Tar_hall_12 = uispinner(app.UIFigure);
            app.Tar_hall_12.Limits = [0 Inf];
            app.Tar_hall_12.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_12ValueChanged, true);
            app.Cur_hall_23 = uilabel(app.UIFigure);
            app.Cur_hall_23.Text = '0';
            app.Tar_hall_22 = uispinner(app.UIFigure);
            app.Tar_hall_22.Limits = [0 Inf];
            app.Tar_hall_22.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_22ValueChanged, true);
            app.Tar_hall_21 = uispinner(app.UIFigure);
            app.Tar_hall_21.Limits = [0 Inf];
            app.Tar_hall_21.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_21ValueChanged, true);
            app.Cur_hall_22 = uilabel(app.UIFigure);
            app.Cur_hall_22.Text = '0';
            app.Tar_hall_31 = uispinner(app.UIFigure);
            app.Tar_hall_31.Limits = [0 Inf];
            app.Tar_hall_31.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_31ValueChanged, true);
            app.Cur_hall_32 = uilabel(app.UIFigure);
            app.Cur_hall_32.Text = '0';
            app.Tar_hall_41 = uispinner(app.UIFigure);
            app.Tar_hall_41.Limits = [0 Inf];
            app.Tar_hall_41.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_41ValueChanged, true);
            app.Cur_hall_42 = uilabel(app.UIFigure);
            app.Cur_hall_42.Text = '0';
            app.Tar_hall_32 = uispinner(app.UIFigure);
            app.Tar_hall_32.Limits = [0 Inf];
            app.Tar_hall_32.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_32ValueChanged, true);
            app.Cur_hall_33 = uilabel(app.UIFigure);
            app.Cur_hall_33.Text = '0';
            app.Tar_hall_42 = uispinner(app.UIFigure);
            app.Tar_hall_42.Limits = [0 Inf];
            app.Tar_hall_42.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_42ValueChanged, true);
            app.Cur_hall_43 = uilabel(app.UIFigure);
            app.Cur_hall_43.Text = '0';
            app.Tar_hall_13 = uispinner(app.UIFigure);
            app.Tar_hall_13.Limits = [0 Inf];
            app.Tar_hall_13.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_13ValueChanged, true);
            app.Cur_hall_14 = uilabel(app.UIFigure);
            app.Cur_hall_14.Text = '0';
            app.Tar_hall_23 = uispinner(app.UIFigure);
            app.Tar_hall_23.Limits = [0 Inf];
            app.Tar_hall_23.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_23ValueChanged, true);
            app.Cur_hall_24 = uilabel(app.UIFigure);
            app.Cur_hall_24.Text = '0';
            app.Tar_hall_33 = uispinner(app.UIFigure);
            app.Tar_hall_33.Limits = [0 Inf];
            app.Tar_hall_33.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_33ValueChanged, true);
            app.Cur_hall_34 = uilabel(app.UIFigure);
            app.Cur_hall_34.Text = '0';
            app.Tar_hall_43 = uispinner(app.UIFigure);
            app.Tar_hall_43.Limits = [0 Inf];
            app.Tar_hall_43.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_43ValueChanged, true);
            app.Cur_hall_44 = uilabel(app.UIFigure);
            app.Cur_hall_44.Text = '0';
            app.Tar_hall_51 = uispinner(app.UIFigure);
            app.Tar_hall_51.Limits = [0 Inf];
            app.Tar_hall_51.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_51ValueChanged, true);
            app.Cur_hall_51 = uilabel(app.UIFigure);
            app.Cur_hall_51.Text = '0';
            app.Tar_hall_52 = uispinner(app.UIFigure);
            app.Tar_hall_52.Limits = [0 Inf];
            app.Tar_hall_52.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_52ValueChanged, true);
            app.Cur_hall_52 = uilabel(app.UIFigure);
            app.Cur_hall_52.Text = '0';
            app.Tar_hall_53 = uispinner(app.UIFigure);
            app.Tar_hall_53.Limits = [0 Inf];
            app.Tar_hall_53.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_53ValueChanged, true);
            app.Cur_hall_53 = uilabel(app.UIFigure);
            app.Cur_hall_53.Text = '0';
            app.Tar_hall_61 = uispinner(app.UIFigure);
            app.Tar_hall_61.Limits = [0 Inf];
            app.Tar_hall_61.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_61ValueChanged, true);
            app.Cur_hall_61 = uilabel(app.UIFigure);
            app.Cur_hall_61.Text = '0';
            app.Tar_hall_54 = uispinner(app.UIFigure);
            app.Tar_hall_54.Limits = [0 Inf];
            app.Tar_hall_54.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_54ValueChanged, true);
            app.Cur_hall_54 = uilabel(app.UIFigure);
            app.Cur_hall_54.Text = '0';
            app.Tar_hall_62 = uispinner(app.UIFigure);
            app.Tar_hall_62.Limits = [0 Inf];
            app.Tar_hall_62.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_62ValueChanged, true);
            app.Tar_hall_63 = uispinner(app.UIFigure);
            app.Tar_hall_63.Limits = [0 Inf];
            app.Tar_hall_63.ValueChangedFcn = createCallbackFcn(app, @Tar_hall_63ValueChanged, true);
            app.Cur_hall_63 = uilabel(app.UIFigure);
            app.Cur_hall_63.Text = '0';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Motion_control_Tab = uitab(app.TabGroup);
            app.Motion_control_Tab.Title = '运动控制';
            app.Motion_control_Tab.ButtonDownFcn = createCallbackFcn(app, @Motion_control_TabButtonDown, true);
            app.uipanel1 = uipanel(app.Motion_control_Tab);
            app.uipanel1.Title = '串口配置';
            app.SetComNum = uidropdown(app.uipanel1);
            app.SetComNum.Items = {'COM1', 'COM2', 'COM3', 'COM4', 'COM5', 'COM6', 'COM7', 'COM8', 'COM9'};
            app.SetComNum.DropDownOpeningFcn = createCallbackFcn(app, @SetComNumDropDownOpening, true);
            app.SetComNum.Value = 'COM6';
            app.SetBaudRate = uidropdown(app.uipanel1);
            app.SetBaudRate.Items = {'2400', '4800', '9600', '14400', '19200', '38400', '57600', '115200', '144000', '200000', '256000', '384000', '480000', '576000', '115200'};
            app.SetBaudRate.DropDownOpeningFcn = createCallbackFcn(app, @SetBaudRateDropDownOpening, true);
            app.SetBaudRate.Value = '9600';
            app.COMSwitch = uibutton(app.uipanel1, 'state');
            app.COMSwitch.ValueChangedFcn = createCallbackFcn(app, @COMSwitchValueChanged, true);
            app.COMSwitch.Text = '打开串口';
            app.text0102 = uilabel(app.uipanel1);
            app.text0102.Text = '波特率';
            app.text0101 = uilabel(app.uipanel1);
            app.text0101.Text = '串口号';
            app.serial_state = uilamp(app.uipanel1);
            app.Keep_goal = uibutton(app.Motion_control_Tab, 'push');
            app.Keep_goal.ButtonPushedFcn = createCallbackFcn(app, @Keep_goalPushed, true);
            app.Keep_goal.Enable = 'off';
            app.Keep_goal.Text = '保存数据';
            app.Send_Button = uibutton(app.Motion_control_Tab, 'push');
            app.Send_Button.ButtonPushedFcn = createCallbackFcn(app, @Send_ButtonPushed, true);
            app.Send_Button.Enable = 'off';
            app.Send_Button.Text = '发送';
            app.Stop_Button = uibutton(app.Motion_control_Tab, 'push');
            app.Stop_Button.ButtonPushedFcn = createCallbackFcn(app, @Stop_ButtonPushed, true);
            app.Stop_Button.Enable = 'off';
            app.Stop_Button.Text = '停止';
            app.All_pre_stohall = uitextarea(app.Motion_control_Tab);
            app.All_pre_stohall.ValueChangedFcn = createCallbackFcn(app, @All_pre_stohallValueChanged, true);
            app.All_pre_stohall.Editable = 'off';
            app.Image2 = uiimage(app.Motion_control_Tab);
            app.Cur_pre_stohall = uitextarea(app.Motion_control_Tab);
            app.Cur_pre_stohall.ValueChangedFcn = createCallbackFcn(app, @Cur_pre_stohallValueChanged, true);
            app.Cur_pre_stohall.Editable = 'off';
            app.Send_goal = uibutton(app.Motion_control_Tab, 'push');
            app.Send_goal.ButtonPushedFcn = createCallbackFcn(app, @Send_goalButtonPushed, true);
            app.Send_goal.Enable = 'off';
            app.Send_goal.Text = '发送数据';
            app.Sethallarray = uidropdown(app.Motion_control_Tab);
            app.Sethallarray.Items = {'0'};
            app.Sethallarray.DropDownOpeningFcn = createCallbackFcn(app, @SethallarrayDropDownOpening, true);
            app.Sethallarray.ValueChangedFcn = createCallbackFcn(app, @SethallarrayValueChanged, true);
            app.Sethallarray.Value = '0';
            app.CheckBox = uicheckbox(app.Motion_control_Tab);
            app.CheckBox.Text = '连续发送';
            app.Switchmodsav = uicheckbox(app.Motion_control_Tab);
            app.Switchmodsav.ValueChangedFcn = createCallbackFcn(app, @SwitchmodsavValueChanged, true);
            app.Switchmodsav.Text = '修改切换';
            app.Wipe_halldata = uibutton(app.Motion_control_Tab, 'push');
            app.Wipe_halldata.ButtonPushedFcn = createCallbackFcn(app, @Wipe_halldataButtonPushed, true);
            app.Wipe_halldata.Enable = 'off';
            app.Wipe_halldata.Text = '清空数据';
            app.EditFilename = uieditfield(app.Motion_control_Tab, 'text');
            app.EditFilename.Value = 'test';
            app.load_file = uibutton(app.Motion_control_Tab, 'push');
            app.load_file.ButtonPushedFcn = createCallbackFcn(app, @load_fileButtonPushed, true);
            app.load_file.Enable = 'off';
            app.load_file.Text = '载入文件';
            app.PID_control_Tab = uitab(app.TabGroup);
            app.PID_control_Tab.Title = 'PID调试';
            app.PID_control_Tab.ButtonDownFcn = createCallbackFcn(app, @PID_control_TabButtonDown, true);
            app.PIDPanel = uipanel(app.PID_control_Tab);
            app.PIDPanel.Title = 'PID参数';
            app.daPID = uipanel(app.PIDPanel);
            app.daPID.Title = '大拇指';
            app.daBend_kp = uieditfield(app.daPID, 'numeric');
            app.daBend_kp.ValueDisplayFormat = '%.2f';
            app.daBend_ki = uieditfield(app.daPID, 'numeric');
            app.daBend_ki.ValueDisplayFormat = '%.2f';
            app.daBend_kd = uieditfield(app.daPID, 'numeric');
            app.daBend_kd.ValueDisplayFormat = '%.2f';
            app.text0202_2 = uilabel(app.daPID);
            app.text0202_2.Text = '弯曲kp';
            app.text0202_3 = uilabel(app.daPID);
            app.text0202_3.Text = '弯曲ki';
            app.text0202_4 = uilabel(app.daPID);
            app.text0202_4.Text = '弯曲kd';
            app.daOpen_kp = uieditfield(app.daPID, 'numeric');
            app.daOpen_kp.ValueDisplayFormat = '%.2f';
            app.daOpen_ki = uieditfield(app.daPID, 'numeric');
            app.daOpen_ki.ValueDisplayFormat = '%.2f';
            app.daOpen_kd = uieditfield(app.daPID, 'numeric');
            app.daOpen_kd.ValueDisplayFormat = '%.2f';
            app.text0202_5 = uilabel(app.daPID);
            app.text0202_5.Text = '伸直kp';
            app.text0202_6 = uilabel(app.daPID);
            app.text0202_6.Text = '伸直ki';
            app.text0202_7 = uilabel(app.daPID);
            app.text0202_7.Text = '伸直kd';
            app.daPID_2 = uipanel(app.PIDPanel);
            app.daPID_2.Title = '食指';
            app.daBend_kp_2 = uieditfield(app.daPID_2, 'numeric');
            app.daBend_kp_2.ValueDisplayFormat = '%.2f';
            app.text0202_8 = uilabel(app.daPID_2);
            app.text0202_8.Text = '弯曲kp';
            app.daOpen_kp_2 = uieditfield(app.daPID_2, 'numeric');
            app.daOpen_kp_2.ValueDisplayFormat = '%.2f';
            app.text0202_9 = uilabel(app.daPID_2);
            app.text0202_9.Text = '伸直kp';
            app.daBend_ki_2 = uieditfield(app.daPID_2, 'numeric');
            app.daBend_ki_2.ValueDisplayFormat = '%.2f';
            app.text0202_10 = uilabel(app.daPID_2);
            app.text0202_10.Text = '弯曲ki';
            app.daOpen_ki_2 = uieditfield(app.daPID_2, 'numeric');
            app.daOpen_ki_2.ValueDisplayFormat = '%.2f';
            app.text0202_11 = uilabel(app.daPID_2);
            app.text0202_11.Text = '伸直ki';
            app.daBend_kd_2 = uieditfield(app.daPID_2, 'numeric');
            app.daBend_kd_2.ValueDisplayFormat = '%.2f';
            app.text0202_12 = uilabel(app.daPID_2);
            app.text0202_12.Text = '弯曲kd';
            app.daOpen_kd_2 = uieditfield(app.daPID_2, 'numeric');
            app.daOpen_kd_2.ValueDisplayFormat = '%.2f';
            app.text0202_13 = uilabel(app.daPID_2);
            app.text0202_13.Text = '伸直kd';
            app.daPID_3 = uipanel(app.PIDPanel);
            app.daPID_3.Title = '中指';
            app.daBend_kp_3 = uieditfield(app.daPID_3, 'numeric');
            app.daBend_kp_3.ValueDisplayFormat = '%.2f';
            app.text0202_14 = uilabel(app.daPID_3);
            app.text0202_14.Text = '弯曲kp';
            app.daOpen_kp_3 = uieditfield(app.daPID_3, 'numeric');
            app.daOpen_kp_3.ValueDisplayFormat = '%.2f';
            app.text0202_15 = uilabel(app.daPID_3);
            app.text0202_15.Text = '伸直kp';
            app.daBend_ki_3 = uieditfield(app.daPID_3, 'numeric');
            app.daBend_ki_3.ValueDisplayFormat = '%.2f';
            app.text0202_16 = uilabel(app.daPID_3);
            app.text0202_16.Text = '弯曲ki';
            app.daOpen_ki_3 = uieditfield(app.daPID_3, 'numeric');
            app.daOpen_ki_3.ValueDisplayFormat = '%.2f';
            app.text0202_17 = uilabel(app.daPID_3);
            app.text0202_17.Text = '伸直ki';
            app.daBend_kd_3 = uieditfield(app.daPID_3, 'numeric');
            app.daBend_kd_3.ValueDisplayFormat = '%.2f';
            app.text0202_18 = uilabel(app.daPID_3);
            app.text0202_18.Text = '弯曲kd';
            app.daOpen_kd_3 = uieditfield(app.daPID_3, 'numeric');
            app.daOpen_kd_3.ValueDisplayFormat = '%.2f';
            app.text0202_19 = uilabel(app.daPID_3);
            app.text0202_19.Text = '伸直kd';
            app.daPID_4 = uipanel(app.PIDPanel);
            app.daPID_4.Title = '无名指';
            app.daBend_kp_4 = uieditfield(app.daPID_4, 'numeric');
            app.daBend_kp_4.ValueDisplayFormat = '%.2f';
            app.text0202_20 = uilabel(app.daPID_4);
            app.text0202_20.Text = '弯曲kp';
            app.daOpen_kp_4 = uieditfield(app.daPID_4, 'numeric');
            app.daOpen_kp_4.ValueDisplayFormat = '%.2f';
            app.text0202_21 = uilabel(app.daPID_4);
            app.text0202_21.Text = '伸直kp';
            app.daBend_ki_4 = uieditfield(app.daPID_4, 'numeric');
            app.daBend_ki_4.ValueDisplayFormat = '%.2f';
            app.text0202_22 = uilabel(app.daPID_4);
            app.text0202_22.Text = '弯曲ki';
            app.daOpen_ki_4 = uieditfield(app.daPID_4, 'numeric');
            app.daOpen_ki_4.ValueDisplayFormat = '%.2f';
            app.text0202_23 = uilabel(app.daPID_4);
            app.text0202_23.Text = '伸直ki';
            app.daBend_kd_4 = uieditfield(app.daPID_4, 'numeric');
            app.daBend_kd_4.ValueDisplayFormat = '%.2f';
            app.text0202_24 = uilabel(app.daPID_4);
            app.text0202_24.Text = '弯曲kd';
            app.daOpen_kd_4 = uieditfield(app.daPID_4, 'numeric');
            app.daOpen_kd_4.ValueDisplayFormat = '%.2f';
            app.text0202_25 = uilabel(app.daPID_4);
            app.text0202_25.Text = '伸直kd';
            app.daPID_5 = uipanel(app.PIDPanel);
            app.daPID_5.Title = '小拇指';
            app.daBend_ki_5 = uieditfield(app.daPID_5, 'numeric');
            app.daBend_ki_5.ValueDisplayFormat = '%.2f';
            app.text0202_28 = uilabel(app.daPID_5);
            app.text0202_28.Text = '弯曲ki';
            app.daOpen_ki_5 = uieditfield(app.daPID_5, 'numeric');
            app.daOpen_ki_5.ValueDisplayFormat = '%.2f';
            app.text0202_29 = uilabel(app.daPID_5);
            app.text0202_29.Text = '伸直ki';
            app.daBend_kd_5 = uieditfield(app.daPID_5, 'numeric');
            app.daBend_kd_5.ValueDisplayFormat = '%.2f';
            app.text0202_30 = uilabel(app.daPID_5);
            app.text0202_30.Text = '弯曲kd';
            app.daOpen_kd_5 = uieditfield(app.daPID_5, 'numeric');
            app.daOpen_kd_5.ValueDisplayFormat = '%.2f';
            app.text0202_31 = uilabel(app.daPID_5);
            app.text0202_31.Text = '伸直kd';
            app.daBend_kp_5 = uieditfield(app.daPID_5, 'numeric');
            app.daBend_kp_5.ValueDisplayFormat = '%.2f';
            app.text0202_26 = uilabel(app.daPID_5);
            app.text0202_26.Text = '弯曲kp';
            app.daOpen_kp_5 = uieditfield(app.daPID_5, 'numeric');
            app.daOpen_kp_5.ValueDisplayFormat = '%.2f';
            app.text0202_27 = uilabel(app.daPID_5);
            app.text0202_27.Text = '伸直kp';
            app.daPID_6 = uipanel(app.PIDPanel);
            app.daPID_6.Title = '手腕';
            app.daBend_kp_6 = uieditfield(app.daPID_6, 'numeric');
            app.daBend_kp_6.ValueDisplayFormat = '%.2f';
            app.text0202_32 = uilabel(app.daPID_6);
            app.text0202_32.Text = '弯曲kp';
            app.daOpen_kp_6 = uieditfield(app.daPID_6, 'numeric');
            app.daOpen_kp_6.ValueDisplayFormat = '%.2f';
            app.text0202_33 = uilabel(app.daPID_6);
            app.text0202_33.Text = '伸直kp';
            app.daBend_ki_6 = uieditfield(app.daPID_6, 'numeric');
            app.daBend_ki_6.ValueDisplayFormat = '%.2f';
            app.text0202_34 = uilabel(app.daPID_6);
            app.text0202_34.Text = '弯曲ki';
            app.daOpen_ki_6 = uieditfield(app.daPID_6, 'numeric');
            app.daOpen_ki_6.ValueDisplayFormat = '%.2f';
            app.text0202_35 = uilabel(app.daPID_6);
            app.text0202_35.Text = '伸直ki';
            app.daBend_kd_6 = uieditfield(app.daPID_6, 'numeric');
            app.daBend_kd_6.ValueDisplayFormat = '%.2f';
            app.text0202_36 = uilabel(app.daPID_6);
            app.text0202_36.Text = '弯曲kd';
            app.daOpen_kd_6 = uieditfield(app.daPID_6, 'numeric');
            app.daOpen_kd_6.ValueDisplayFormat = '%.2f';
            app.text0202_37 = uilabel(app.daPID_6);
            app.text0202_37.Text = '伸直kd';
            app.PIDButton = uibutton(app.PID_control_Tab, 'push');
            app.PIDButton.Text = '读取PID';
            app.PIDButton_2 = uibutton(app.PID_control_Tab, 'push');
            app.PIDButton_2.Text = '发送PID';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '测试';
            app.UITable = uitable(app.Tab_2);
            app.UITable.ColumnName = {'1'; '2'; '3'; '4'; '5'; '6'; '7'; '8'; '9'; '10'; '11'; '12'; '13'; '14'; '15'};
            app.UITable.RowName = {};
            app.Cur_hall_62 = uilabel(app.UIFigure);
            app.Cur_hall_62.Text = '0';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = right_yanghao_hand_controller

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end